classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel  matlab.ui.container.Panel
        STARTButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        MENUPanel                      matlab.ui.container.Panel
        ClasificarButton               matlab.ui.control.Button
        EntrenarButton                 matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ClassificationPanel            matlab.ui.container.Panel
        BoundingBoxcolorDropDown       matlab.ui.control.DropDown
        BoundingBoxcolorDropDownLabel  matlab.ui.control.Label
        DetectButton                   matlab.ui.control.Button
        Image_to_detect                matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtomPushed(app, event)
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Visible = 'off';
            app.MENUPanel.Visible = 'on';
        end

        % Callback function
        function CLASSIFICATION_Buttom_Pushed(app, event)
            app.MENUPanel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Button pushed function: DetectButton
        function DetectButton_Pushed(app, event)
             try
                    loadedData = load('trainedDetector_yolox_10e.mat');
                    detector = loadedData.detector;


                    % Leer la imagen para detección
                    imageToDetect = imread(app.Image_to_detect.ImageSource);  % Cambia a la imagen que prefieras
        
                    % Verificar el tipo de dato de la imagen
                    disp(['Tipo de dato de la imagen antes de conversión: ', class(imageToDetect)]);
                    if ~isa(imageToDetect, 'uint8')
                        imageToDetect = im2uint8(imageToDetect);
                    end
                    disp(['Tipo de dato de la imagen después de conversión: ', class(imageToDetect)]);
        
                    % Realizar la detección en la imagen
                    [bboxes, scores, labels] = detect(detector, imageToDetect, 'Threshold', 0.75, 'AutoResize', false);
                    
                    color = app.BoundingBoxcolorDropDown.Value;
                    % Anotar la imagen con los resultados de detección
                    annotatedImage = insertObjectAnnotation(imageToDetect, "rectangle", bboxes, labels, 'Color', color);
        
                    % Actualizar la imagen en el componente Image
                    app.Image_to_detect.ImageSource = annotatedImage;
                catch ME
                    % Mostrar un mensaje de error detallado en caso de fallo
                    disp('Error durante la detección:');
                    disp(ME.message);
                end
        end

        % Button pushed function: ClasificarButton
        function Classify_Button_pushed(app, event)
            app.MENUPanel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Value changed function: BoundingBoxcolorDropDown
        function BB_color_changed(app, event)
            value = app.BoundingBoxcolorDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClassificationPanel = uipanel(app.UIFigure);
            app.ClassificationPanel.Title = 'Classification';
            app.Image3 = uiimage(app.ClassificationPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'smooth-elegant-transparent-blue-cloth.jpg');
            app.Image_to_detect = uiimage(app.ClassificationPanel);
            app.Image_to_detect.ImageSource = fullfile(pathToMLAPP, '15.jpg');
            app.DetectButton = uibutton(app.ClassificationPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButton_Pushed, true);
            app.DetectButton.Icon = fullfile(pathToMLAPP, 'images', 'pngtree-vector-star-icon-png-image_5169247.jpg');
            app.DetectButton.Text = 'Detect';
            app.BoundingBoxcolorDropDownLabel = uilabel(app.ClassificationPanel);
            app.BoundingBoxcolorDropDownLabel.Text = 'Bounding Box color';
            app.BoundingBoxcolorDropDown = uidropdown(app.ClassificationPanel);
            app.BoundingBoxcolorDropDown.Items = {'blue', 'orange', 'red', 'yellow'};
            app.BoundingBoxcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @BB_color_changed, true);
            app.BoundingBoxcolorDropDown.Value = 'yellow';
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.MENUPanel.Visible = 'off';
            app.Image2 = uiimage(app.MENUPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', '1870218e6a5192b81d5e4078405537e6.jpg');
            app.EntrenarButton = uibutton(app.MENUPanel, 'push');
            app.EntrenarButton.Text = 'Entrenar';
            app.ClasificarButton = uibutton(app.MENUPanel, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @Classify_Button_pushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel = uipanel(app.UIFigure);
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Title = 'RECONOCIMIENTO DE PERSONAS EN ENTORNOS NATURALES';
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Visible = 'off';
            app.Image = uiimage(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'main_panel.jpg');
            app.STARTButton = uibutton(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtomPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end